classdef MySnakeCOMPLETED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VOICECONTROLEDLabel  matlab.ui.control.Label
        SNAKEGAMELabel       matlab.ui.control.Label
        STARTButton          matlab.ui.control.Button
        BoundsCheckBox       matlab.ui.control.CheckBox
        Title_Image          matlab.ui.control.Image
        Lborder_img          matlab.ui.control.Image
        Rborder_img          matlab.ui.control.Image
        Uborder_img          matlab.ui.control.Image
        Dborder_img          matlab.ui.control.Image
        PRESSTOSPEAKButton   matlab.ui.control.Button
        Image                matlab.ui.control.Image
        SAYUPDOWNRIGHTLEFTLOUDANDCLEARLabel  matlab.ui.control.Label
        SpeedSliderLabel     matlab.ui.control.Label
        SpeedSlider          matlab.ui.control.Slider
        ScoreEditFieldLabel  matlab.ui.control.Label
        ScoreEditField       matlab.ui.control.NumericEditField
        LoseLabel            matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dir = 0;  %0=left 1=up 2=right 3=down
        score = 0;
        v=1; %speed (seconds delay till next move of snake)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.BoundsCheckBox.Visible = 'off'; %hide bound button while start
            app.STARTButton.Visible = 'off'; %hide start button while start
            app.SpeedSlider.Visible = 'off'; %hide speed changer while start
            app.SpeedSliderLabel.Visible = 'off'; %  ^^
            app.PRESSTOSPEAKButton.Visible = 'on'; %show press to speak button while start
            app.LoseLabel.Visible = 'off';  % hide lose label while start

            app.score = 0;
            dim = 20;   
            app.dir = randi([0,3]); %random start direction of snake
            cla(app.UIAxes) %clear axis
            
            %app.v = 1;   %speed (sec)
            a = randi([5 15]);  %random 1st spawm location of food
            b = randi([5 15]);  
            f = [a b];
            s = zeros(100,2);
            s_old = zeros (100,2);
            s(1,1) = randi([5 15]); %random 1st spawm location of snake
            s(1,2) = randi([5 15]);
            snake = plot(app.UIAxes,s([1 2],1),s([1 2],2),'Marker','o','MarkerSize',7,'LineStyle',':','LineWidth',3,'color','green');
            hold(app.UIAxes,'on');
            food = plot(app.UIAxes,f(1,1),f(1,2),'Marker','hexagram','MarkerSize',2,'MarkerFaceColor','r','LineWidth',5 ,'color','white');
            
            xlim(app.UIAxes,[1 20])
            ylim(app.UIAxes,[1 20])
            
            while (1)
                id = 0;
                s_old = s;
                               
                %Update Head Position
                switch app.dir
                    case 0                  %0=left
                        s(1,1)=s_old(1,1)-1;
                        s(1,2)=s_old(1,2);
                    case 1                  %1=up
                        s(1,1)=s_old(1,1);
                        s(1,2)=s_old(1,2)+1;
                    case 2                  %2=right
                        s(1,1)=s_old(1,1)+1;
                        s(1,2)=s_old(1,2);
                    case 3                  %3=down
                        s(1,1)=s_old(1,1);
                        s(1,2)=s_old(1,2)-1;
                end
                
                %Update Body Position
                for i=2:1:length(nonzeros(s(:,1)))
                    s(i,1) = s_old(i-1,1);
                    s(i,2) = s_old(i-1,2);
                end
                
                %Limit area
                if(app.BoundsCheckBox.Value==1)  
                    if(s(1,1)>dim||s(1,1)<0||s(1,2)>dim||s(1,2)<0)
                        app.LoseLabel.Visible = 'on';  % show lose label while lose
                        app.BoundsCheckBox.Visible = 'on'; %show bound button while lose
                        app.STARTButton.Visible = 'on'; %show start button while lose
                        app.SpeedSlider.Visible = 'on'; %show speed changer while lose
                        app.SpeedSliderLabel.Visible = 'on'; %  ^^
                        app.PRESSTOSPEAKButton.Visible = 'off';  %hide press to speak button while lose
                        cla(app.UIAxes) %clear axis
                        break
                    end
                elseif(app.BoundsCheckBox.Value==0)  %if you play withoud bounds
                    if(s(1,1)==-1 && s(1,2)>0) 
                        s(1,1)=s_old(1,1)+20;       %left hit to appear from right
                        s(1,2)=s_old(1,2);
                    elseif(s(1,2)==-1 && s(1,1)>0) 
                        s(1,1)=s_old(1,1);
                        s(1,2)=s_old(1,2)+20;       %down hit to appear from top
                    elseif(s(1,1)==20 && s(1,2)>0)
                        s(1,1)=s_old(1,1)-20;       %right hit to appear from left
                        s(1,2)=s_old(1,2);  
                    elseif(s(1,2)==20 && s(1,1)>0)
                        s(1,1)=s_old(1,1);
                        s(1,2)=s_old(1,2)-20;       %upper hit to appear from down                  
                    end
                end
                
                %Touch body
                if(isempty(find(prod(s(1,1:2)==s(2:end,1:2),2)))==0)
                    app.LoseLabel.Visible = 'on';  % show lose label while lose
                    app.PRESSTOSPEAKButton.Visible = 'off';  %hide press to speak button while lose
                    cla(app.UIAxes) %clear axis        
                    app.BoundsCheckBox.Visible = 'on'; %show bound button while lose
                    app.STARTButton.Visible = 'on'; %show start button while lose
                    app.SpeedSlider.Visible = 'on'; %show speed changer while lose
                    app.SpeedSliderLabel.Visible = 'on'; %  ^^
                    break
                end
                
                %Eating
                if(s(1,[1 2])==f)
                    s(length(nonzeros(s(:,1)))+1,1) = s_old(length(nonzeros(s_old(:,1))),1);
                    s(length(nonzeros(s(:,2)))+1,2) = s_old(length(nonzeros(s_old(:,2))),2);
                    f(1) = round(rand*dim);
                    f(2) = round(rand*dim);
                    app.score = app.score+1;
                end
                
                %Plotting
                app.ScoreEditField.Value=app.score;
                set(snake,'XData',nonzeros(s(:,1)),'YData',nonzeros(s(:,2)));
                set(food,'XData',nonzeros(f(:,1)),'YData',nonzeros(f(:,2)));
                pause(app.v)
            end
        end

        % Value changed function: BoundsCheckBox
        function BoundsCheckBoxValueChanged(app, event)
            % 1(checked)=with bounds, 0=without bounds
            if(app.BoundsCheckBox.Value==0)
               app.Uborder_img.Visible = 'off'; %hide borders
               app.Dborder_img.Visible = 'off'; 
               app.Rborder_img.Visible = 'off'; 
               app.Lborder_img.Visible = 'off'; 
            elseif(app.BoundsCheckBox.Value==1)
               app.Uborder_img.Visible = 'on';  %show borders
               app.Dborder_img.Visible = 'on'; 
               app.Rborder_img.Visible = 'on'; 
               app.Lborder_img.Visible = 'on'; 
            end                
        end

        % Button pushed function: PRESSTOSPEAKButton
        function PRESSTOSPEAKButtonPushed(app, event)
        load('params.mat');
        
        recObj=audiorecorder(8000,16,1);  %(Fs, nBits, channels)
        fs=8000;
        
        recordblocking(recObj,2); % 2 seconds => 16,000 SAMPLES
        
        x=getaudiodata(recObj);
        x=x(1:16000);
        
        windowLength = round(0.025*fs); %25ms window | 200 frames
        overlapLength = round(0.019*fs); %19ms overlap | 152 frames
        
        %%  CLIPPING SOUND AND MOVING TO START
        xmax = movmax(x,60);
        xclipped = x(xmax>0.015); %we keep only values exceeds 0.015
        xclipped2 = zeros(16000,1); %create table with full of zeros with size of 16000, as many as the samples of 2 seconds
        for i= 1:length(xclipped)
            xclipped2(i,1)=xclipped(i,1); %paste the signal data from the starting point of time
        end
        %%
        
        win = hamming(windowLength,"periodic"); 
        coeffs = mfcc(xclipped2,fs,"Window",win,"OverlapLength",overlapLength,"NumCoeffs",13,"LogEnergy","ignore");
        
        a=coeffs(1);
        
        TestMFCCoeffs2=cell(330,1);  %coeffs of sound input
        for i=1:330
            a=coeffs(i,1:13);
            a=a';
            TestMFCCoeffs2{i}=a;
           
        end
        
        InputCommand = cell2mat(TestMFCCoeffs2); 
        
        avgDown= cell2mat(AvgMFCCTrainDown); %coeffs of stored commands 
        avgUp= cell2mat(AvgMFCCTrainUp);
        avgLeft= cell2mat(AvgMFCCTrainLeft);
        avgRight= cell2mat(AvgMFCCTrainRight);
        
        min_Left = dtw(avgLeft,InputCommand);  % distance calculation with dynamic time warping between the 2 signals(stored command and input command)
        min_Down = dtw(avgDown,InputCommand);
        min_Right = dtw(avgRight,InputCommand);
        min_Up = dtw(avgUp,InputCommand);
        
        %0=left 1=up 2=right 3=down
        if min_Left>=min_Right && min_Down>=min_Right && min_Up>=min_Right
            if app.dir~=0 %if snake's route is leftward cant instantly go rightward
                app.dir=2;
            end
        elseif min_Left>=min_Down && min_Right>=min_Down && min_Up>=min_Down
            if app.dir~=1 %if snake's route is upward cant instantly go downward
                app.dir=3;
            end
        elseif min_Down>=min_Left && min_Right>=min_Left && min_Up>=min_Left
            if app.dir~=2 %if snake's route is rightward cant instantly go leftward
                app.dir=0;
            end
        elseif min_Left>=min_Up && min_Down>=min_Up && min_Right>=min_Up
            if app.dir~=3 %if snake's route is downward cant instantly go upward
                app.dir=1;
            end
        end
        
        %ENDS
  

        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            %value = app.SpeedSlider.Value;
            if app.SpeedSlider.Value==1 %slow speed, move every 1.7 sec
                app.v = 1.7; 
            elseif app.SpeedSlider.Value==2  %normal speed, move every 1 sec
                app.v = 1;
            elseif app.SpeedSlider.Value==3  %fast speed, move every 0.3 sec
                app.v = 0.3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VOICECONTROLEDLabel = uilabel(app.UIFigure);
            app.VOICECONTROLEDLabel.Text = 'VOICE CONTROLED ';
            app.SNAKEGAMELabel = uilabel(app.UIFigure);
            app.SNAKEGAMELabel.Text = 'SNAKE GAME';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.BoundsCheckBox = uicheckbox(app.UIFigure);
            app.BoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundsCheckBoxValueChanged, true);
            app.BoundsCheckBox.Text = 'Bounds';
            app.BoundsCheckBox.Value = true;
            app.Title_Image = uiimage(app.UIFigure);
            app.Title_Image.ImageSource = 'snakeTitle.png';
            app.Lborder_img = uiimage(app.UIFigure);
            app.Lborder_img.ImageSource = 'border_img.png';
            app.Rborder_img = uiimage(app.UIFigure);
            app.Rborder_img.ImageSource = 'border_img.png';
            app.Uborder_img = uiimage(app.UIFigure);
            app.Uborder_img.ImageSource = 'border_img1.png';
            app.Dborder_img = uiimage(app.UIFigure);
            app.Dborder_img.ImageSource = 'border_img1.png';
            app.PRESSTOSPEAKButton = uibutton(app.UIFigure, 'push');
            app.PRESSTOSPEAKButton.ButtonPushedFcn = createCallbackFcn(app, @PRESSTOSPEAKButtonPushed, true);
            app.PRESSTOSPEAKButton.Visible = 'off';
            app.PRESSTOSPEAKButton.Text = 'PRESS TO SPEAK';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'imageedit_8_4707392012.png';
            app.SAYUPDOWNRIGHTLEFTLOUDANDCLEARLabel = uilabel(app.UIFigure);
            app.SAYUPDOWNRIGHTLEFTLOUDANDCLEARLabel.Text = {'SAY '; '"UP" "DOWN" "RIGHT" "LEFT"'; 'LOUD AND CLEAR'};
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [1 3];
            app.SpeedSlider.MajorTicks = [1 2 3];
            app.SpeedSlider.MajorTickLabels = {'slow', 'normal', 'fast'};
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.MinorTicks = [1 2 3];
            app.SpeedSlider.Value = 2;
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoseLabel = uilabel(app.UIFigure);
            app.LoseLabel.Visible = 'off';
            app.LoseLabel.Text = {'YOU LOST! '; 'BETTER LUCK NEXT TIME'};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MySnakeCOMPLETED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end